classdef QuickCriteria < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        ColumnnumberSpinner  matlab.ui.control.Spinner
        Label_2              matlab.ui.control.Label
        DegreeSpinner        matlab.ui.control.Spinner
        Label                matlab.ui.control.Label
        TabGroup             matlab.ui.container.TabGroup
        DataTab              matlab.ui.container.Tab
        dataTable            matlab.ui.control.Table
        MathexpectationTab   matlab.ui.container.Tab
        mathTable            matlab.ui.control.Table
        CriteriaButtonGroup  matlab.ui.container.ButtonGroup
        AbbeButton           matlab.ui.control.RadioButton
        AscDecButton         matlab.ui.control.RadioButton
        MedianButton         matlab.ui.control.RadioButton
        StartButton          matlab.ui.control.Button
        InfoTextArea         matlab.ui.control.TextArea
        InfoTextAreaLabel    matlab.ui.control.Label
        FileEF               matlab.ui.control.EditField
        FileButton           matlab.ui.control.Button
        UIAxesHist           matlab.ui.control.UIAxes
        UIAxesAp             matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        FileName
        path

        % Criteria parameters
        q_crit_abbe = 3;
        R_crit = 3;

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: FileButton
        function FileButtonPushed(app, event)
            [app.FileName,app.path] = uigetfile();
            if isequal(app.FileName,0)
               app.InfoTextArea.Value = 'The user has not selected a file!';
            else
               app.FileEF.Value = fullfile(app.path, app.FileName);
            end

        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            try
                % Loading data
                degree_af = app.DegreeSpinner.Value; % Degree of approximating polynomial
                full_path = fullfile(app.path, app.FileName);
                mas = readmatrix(full_path);
                number_column = app.ColumnnumberSpinner.Value;


                [count_row, count_column] = size(mas);
                app.dataTable.Data = mas;
                app.dataTable.ColumnName = compose("%d", 1:count_column);
                
                % Initializing result arrays
                mas_criteria_go = zeros(1, count_column);
                mas_criteria_z = zeros(1, count_column);
                mas_criteria = zeros(1, count_column);
                mas_mx = zeros(1, count_column);
                mas_dx = zeros(1, count_column);


                % Getting the selected criterion
                selectedButton = app.CriteriaButtonGroup.SelectedObject;
                selectedCriteria = selectedButton.Tag;


                for i = 1:count_column
                    column_data = mas(:, i);

                    switch selectedCriteria
                        case 'MedianButton'
                            mas_criteria(i) = criterion_median(column_data);
                            
                        case 'AscDecButton' 
                            mas_criteria(i) = criterion_seria(column_data);   
                                                   
                        case 'AbbeButton'
                            mas_criteria(i) = criterion_abbe(column_data, app.q_crit_abbe);
                    end

                    mas_criteria_go(i) = criterion_error(column_data, app.R_crit);
                    
                    % Data after processing a gross error                   
                    coloumn_data_go = DelHardErr(column_data, app.R_crit);       
                    column_data = coloumn_data_go;
                    column_data_o = column_data';

                    mas_criteria_z(i) = criterion_goodness(column_data);
                    
                    % Calculating statistics
                    mas_mx(i) = mean(column_data);
                    mas_dx(i) = std(column_data, 1);

                    if number_column == i
                        data = column_data_o;
                        N = length(data);
                        % Sturges' Rule
                        k=1+floor(log2(N));
                        % Finding the maximum and minimum
                        max_m = max(data);
                        min_m = min(data);
                        % Calculate the width of each interval
                        h = (max_m - min_m)/k;
                        % Array of frequencies of values ��in each interval
                        mas_histo = zeros(1,k);
                         % Estimation of the parameters of normal distribution:
                            mu = sum(data)/N;
                            sig_sum = zeros(1, N);
                            for i=1:N
                                sig_sum(i) = (data(i)-mu)^2;
                            end
                            sig = sqrt(sum(sig_sum)/N);

                        % Cicle
                        minm = min_m;

                        for i=1:N
                            for j = 1:k
                              
                                if (data(i) > minm+(j-1)*h) && (data(i) <= minm+j*h)
                                    mas_histo(j)=mas_histo(j)+1;
                                end
                            end
                        end
                        
                        % Create an array with interval boundaries (Sturges)
                        borders = zeros(1, k+1);
                        borders(1) = min_m;
                        for i=2:k+1
                            borders(i) = borders(i-1) + h; 
                        end    
                        
                        % Probability according to normal distribution for each interval
                        P = zeros(1, k);
                        for i=1:k
                            P(i) = normcdf(borders(i+1), mu, sig) - normcdf(borders(i), mu, sig);
                        end
                        
                        % Expected number of observations in the interval
                        E = zeros(1, k);
                        for i=1:k
                            E(i) = ceil(N * P(i));
                        end
                        
                        bar(app.UIAxesHist, mas_histo)
                        hold(app.UIAxesHist, 'on')
                        plot(app.UIAxesHist, E)
                        hold(app.UIAxesHist, 'off')

                    end
                end
                
                t_mas_criteria_go = cell(1, count_column);
                t_mas_criteria_z = cell(1, count_column);
                t_mas_criteria = cell(1, count_column);

                for i = 1:count_column
                    if mas_criteria_go(i) == 0
                        t_mas_criteria_go{i} = 'present';
                    else
                        t_mas_criteria_go{i} = 'absent';
                    end

                    if mas_criteria_z(i) == 1
                        t_mas_criteria_z{i} = 'normal';
                    else
                        t_mas_criteria_z{i} = 'abnormal';
                    end

                    if mas_criteria(i) == 0
                        t_mas_criteria{i} = 'present';
                    else
                        t_mas_criteria{i} = 'absent';
                    end
                end


                X_ = 0:0.5:(count_column*0.5-0.5);

                % Calculation of the OLS coefficients based on the original data
                lsm_ratio = lsm(X_, mas_mx, degree_af);
                str_ratio = 'F(x) = '; % Start of expression

                for i = 1:(degree_af + 1)
                    ratio = lsm_ratio(i);

                    if ratio == 0
                        continue
                    end

                    % Sign
                    if ratio > 0 && i > 1
                        sign_str = ' + ';
                    elseif ratio < 0
                        sign_str = ' - ';
                    else
                        sign_str = '';
                    end

                    % Form the coefficient
                    abs_ratio = abs(ratio);
                    if abs_ratio == 1 && i > 1
                        coef_str = '';
                    else
                        coef_str = num2str(abs_ratio);
                    end

                    % Form the degree x
                    if i == 1
                        x_str = '';
                    elseif i == 2
                        x_str = 'x';
                    else
                        x_str = ['x^' num2str(i-1)];
                    end

                    str_ratio = [str_ratio sign_str coef_str x_str];
                end

                % Preparing data for the table
                table_data = [
                    t_mas_criteria;
                    t_mas_criteria_go; 
                    t_mas_criteria_z;                     
                    num2cell(mas_mx); 
                    num2cell(mas_dx)
                ];
                
                app.mathTable.Data = table_data;
                app.mathTable.ColumnName = compose("%d", 1:count_column);
                app.mathTable.RowName = {'Result of the criterion'; 'Crit. gross error'; 'Goodness-of-fit crit.'; 'Math. expectation'; 'Std. deviation'};
                
                app.InfoTextArea.Value = str_ratio;
                
                % Calculating the values ��of the approximating function
                print_Faf = Faf(lsm_ratio, X_);
                
                % Plotting graphs
                plot(app.UIAxesAp, X_, mas_mx, 'b', 'LineWidth', 1.5); % Original average values
                hold(app.UIAxesAp, 'on') 
                plot(app.UIAxesAp, X_, print_Faf, 'r', 'LineWidth', 0.8); % Approximation
                hold (app.UIAxesAp, 'off')


            catch ME
                app.InfoTextArea.Value = sprintf('Error: %s', ME.message);
            end   

        end

        % Selection changed function: CriteriaButtonGroup
        function CriteriaButtonGroupSelectionChanged(app, event)
            selectedButton = app.CriteriaButtonGroup.SelectedObject;

            if iscell(selectedButton)
                selectedButton = selectedButton{1};
            end
            
            % Convert button text to string
            buttonText = selectedButton.Text;
            if iscell(buttonText)
                buttonText = strjoin(buttonText, ' ');
            end
            
            app.InfoTextArea.Value = sprintf('Criterion selected: %s', buttonText);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxesAp = uiaxes(app.UIFigure);
            title(app.UIAxesAp, 'Approximation')
            xlabel(app.UIAxesAp, 'X')
            ylabel(app.UIAxesAp, 'Y')
            zlabel(app.UIAxesAp, 'Z')
            app.UIAxesHist = uiaxes(app.UIFigure);
            title(app.UIAxesHist, 'Histogram')
            xlabel(app.UIAxesHist, 'X')
            ylabel(app.UIAxesHist, 'Y')
            zlabel(app.UIAxesHist, 'Z')
            app.FileButton = uibutton(app.UIFigure, 'push');
            app.FileButton.ButtonPushedFcn = createCallbackFcn(app, @FileButtonPushed, true);
            app.FileButton.Text = 'File...';
            app.FileEF = uieditfield(app.UIFigure, 'text');
            app.InfoTextAreaLabel = uilabel(app.UIFigure);
            app.InfoTextAreaLabel.Text = 'Info';
            app.InfoTextArea = uitextarea(app.UIFigure);
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.CriteriaButtonGroup = uibuttongroup(app.UIFigure);
            app.CriteriaButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @CriteriaButtonGroupSelectionChanged, true);
            app.CriteriaButtonGroup.Title = 'Criteria for determining the presence of systematic errors';
            app.MedianButton = uiradiobutton(app.CriteriaButtonGroup);
            app.MedianButton.Tag = 'MedianButton';
            app.MedianButton.Text = 'Series criterion based on the sample median';
            app.MedianButton.Value = true;
            app.AscDecButton = uiradiobutton(app.CriteriaButtonGroup);
            app.AscDecButton.Tag = 'NishVoshButton';
            app.AscDecButton.Text = 'The criterion of "ascending" and "descending" series';
            app.AbbeButton = uiradiobutton(app.CriteriaButtonGroup);
            app.AbbeButton.Tag = 'AbbeButton';
            app.AbbeButton.Text = 'Test of squares of successive differences (Abbe test)';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DataTab = uitab(app.TabGroup);
            app.DataTab.Title = 'Data';
            app.dataTable = uitable(app.DataTab);
            app.dataTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.dataTable.RowName = {};
            app.MathexpectationTab = uitab(app.TabGroup);
            app.MathexpectationTab.Title = 'Math. expectation';
            app.mathTable = uitable(app.MathexpectationTab);
            app.mathTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.mathTable.RowName = {};
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Degree of the approximating polynomial:';
            app.DegreeSpinner = uispinner(app.UIFigure);
            app.DegreeSpinner.Value = 5;
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = 'Column number';
            app.ColumnnumberSpinner = uispinner(app.UIFigure);
            app.ColumnnumberSpinner.Value = 10;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = QuickCriteria

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end